classdef OmniSpectrometer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LeftPanelGrid                   matlab.ui.container.GridLayout
        ControlGroup                    matlab.ui.container.TabGroup
        MainControlsTab                 matlab.ui.container.Tab
        MainGridLayout                  matlab.ui.container.GridLayout
        AbsoluteIrradianceButton        matlab.ui.control.Button
        IntegrationTimesEditFieldLabel  matlab.ui.control.Label
        SaveRawSpectrumandBGButton      matlab.ui.control.Button
        SaveSpectrumButton              matlab.ui.control.Button
        NoBGButton                      matlab.ui.control.Button
        SetBGButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartLiveViewButton             matlab.ui.control.Button
        IntegrationTimesEditField       matlab.ui.control.NumericEditField
        SelectSpectrometerDropDown      matlab.ui.control.DropDown
        AutomaticButton                 matlab.ui.control.StateButton
        RunTime                         matlab.ui.control.Label
        RunTimeLabel                    matlab.ui.control.Label
        GeographicTab                   matlab.ui.container.Tab
        GeoGridLayout                   matlab.ui.container.GridLayout
        SolarZenithAngle                matlab.ui.control.Label
        SZALabel                        matlab.ui.control.Label
        TimeZoneEditField               matlab.ui.control.EditField
        TimeZoneEditFieldLabel          matlab.ui.control.Label
        AZMRotationEditField            matlab.ui.control.NumericEditField
        AZMRotationEditFieldLabel       matlab.ui.control.Label
        SlopeEditField                  matlab.ui.control.NumericEditField
        SlopeEditFieldLabel             matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        PressureEditField               matlab.ui.control.NumericEditField
        PressureEditFieldLabel          matlab.ui.control.Label
        ElevationEditField              matlab.ui.control.NumericEditField
        ElevationEditFieldLabel         matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        ConnectSpectrometersButton      matlab.ui.control.Button
        NumOfSpectrometersLabel         matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        PlotGrid                        matlab.ui.container.GridLayout
        SpectrometersGrid               matlab.ui.container.GridLayout
        Spectrometer4CheckBox           matlab.ui.control.CheckBox
        Spectrometer3CheckBox           matlab.ui.control.CheckBox
        Spectrometer2CheckBox           matlab.ui.control.CheckBox
        Spectrometer1CheckBox           matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        spectrometerObj        % Create MATLAB Instrument OmniDriver object
        numOfSpectrometers
        spectrometerIndex
        channelIndex
        integrationTime
        integrationTimeOld
        timeObj
        timeRun
        wavelength
        rawSpectrum
        bgSpectrum
        isConnected
        init
        activeIndex
        runTime
        isRunning
        isAuto
        legendLabel
        scanToAvg
        scanIndex
        scanMaxValues
    end

    methods (Access = private)

        function [wavelengthData, spectralData] = changeSpectrometer(app)
            wavelengthData = cell(1, app.numOfSpectrometers);
            spectralData = cell(1, app.numOfSpectrometers);
            for i = app.activeIndex
                wavelengthData{i + 1} = invoke(app.spectrometerObj, 'getWavelengths', i, app.channelIndex);
                spectralData{i + 1} = invoke(app.spectrometerObj, 'getSpectrum', i);
                
                % The first data is an outlier
                wavelengthData{i + 1} = wavelengthData{i + 1}(2:end);
                spectralData{i + 1} = spectralData{i + 1}(2:end);
            end
        end

        function LiveSpectrum(app, ~, ~)
            [wavelengthData, spectralData] = app.changeSpectrometer();
            app.wavelength = wavelengthData;
            app.rawSpectrum = spectralData;

            if app.init
                app.NoBGButtonPushed()
                app.IntegrationTimesEditFieldValueChanged()
                app.init = false;
            end
            bg = app.bgSpectrum;
            
            cla(app.UIAxes);
            hold(app.UIAxes, 'on')
            
            for i = app.activeIndex
                plot(wavelengthData{i + 1}, spectralData{i + 1} - bg{i + 1}, 'Parent', app.UIAxes);
            end
            hold(app.UIAxes, 'off')

            % Create a cell array of legend labels
            for i = 1:numel(app.activeIndex)
                legendLabels{i} = sprintf('%s', app.legendLabel(app.activeIndex(i) + 1));
            end
            
            % Show the legends
            legend(app.UIAxes, legendLabels);
            axis(app.UIAxes, 'tight')
        end

        function LiveTime(app, ~, ~)
            app.runTime = app.runTime + 1;
            
            totalSeconds = app.runTime;
            hours = floor(totalSeconds / 3600);
            minutes = floor(mod(totalSeconds, 3600) / 60);
            seconds = floor(mod(totalSeconds, 60));
            
            formattedTime = sprintf('%02d:%02d:%02d', hours, minutes, seconds);

            app.RunTime.Text = formattedTime;

            % Automatic saving of spectrum files every 300 seconds
            autoSaveInterval = 10;
            if mod(app.runTime, autoSaveInterval) == 0
                AutoSaveSpectrum(app)
            end

            i = app.SelectSpectrometerDropDown.Value + 1;
            if app.isAuto(i)
                % Queue the current max value
                app.scanMaxValues(app.scanIndex) = max(app.rawSpectrum{i});

                app.scanIndex = app.scanIndex + 1;
                if app.scanIndex > app.scanToAvg
                    app.scanIndex = 1;
                end
                
                if app.scanIndex == 1
                    maxValuesMean = mean(app.scanMaxValues);
                    checkAutoIntegrationTime(app, i, maxValuesMean)
                    app.scanMaxValues = zeros(app.scanToAvg, 1);
                end
            end
        end

        function AutoSaveSpectrum(app)
            wavelengthData = single(cell2mat(app.wavelength));
            rawSpectralData = single(cell2mat(app.rawSpectrum));
            bg = single(cell2mat(app.bgSpectrum));
            
            path = fullfile('../Save/', datestr(now, 'yymmdd'));
            if ~isfolder(path)
                mkdir(path)
            end

            file = sprintf('%s_%ds.mat', datestr(now, 'HHMM'), app.IntegrationTimesEditField.Value);
            save(fullfile(path, file), 'wavelengthData', 'rawSpectralData', 'bg');
        end

        function checkAutoIntegrationTime(app, i, maxValuesMean)
            app.IntegrationTimesEditField.Value = app.integrationTime(i) / 1000000;
            maxIntensity = invoke(app.spectrometerObj, 'getMaximumIntensity', app.spectrometerIndex);
            safeIntensity = maxIntensity * 0.1;
        
            if maxValuesMean > maxIntensity
                newITValue = app.IntegrationTimesEditField.Value / 2;
            else
                integrationTimeRatio = maxValuesMean / safeIntensity;
                newITValue = app.IntegrationTimesEditField.Value / integrationTimeRatio;
            end

            % prevent large bias in the retrieved spectral reflectance from fast‐varying light conditions
            % a maximum integration time of 8s is set for any illumination conditions
            if newITValue > 8
                app.IntegrationTimesEditField.Value = 8;
            else
                app.IntegrationTimesEditField.Value = newITValue;
            end
            app.IntegrationTimesEditFieldValueChanged();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(gcp('nocreate'))
            parpool('threads');

            app.timeObj = timer(...
                'ExecutionMode', 'fixedSpacing', ...    % Run timer repeatedly
                'Period', 0.1, ...                      % Period is 0.1 second
                'BusyMode', 'queue', ...                % Queue timer callbacks when busy
                'TimerFcn', @app.LiveSpectrum);         % Specify callback function

            app.timeRun = timer( ...
                'ExecutionMode','fixedRate', ...
                'Period', 1, ...
                'BusyMode', 'queue', ...
                'TimerFcn', @app.LiveTime);

            app.spectrometerIndex = 0;      % first spectrometer by default
            app.channelIndex = 0;           % first channel by default
            app.isConnected = false;
            app.activeIndex = 0;
            app.init = true;
            app.runTime = 0;
            app.isRunning = false;
            app.legendLabel = string.empty(0, 1);
            app.integrationTime = [];
            app.integrationTimeOld = [];
            app.isAuto = [];

        end

        % Button pushed function: ConnectSpectrometersButton
        function ConnectSpectrometersButtonPushed(app, event)
            app.spectrometerObj = icdevice('OceanOptics_OmniDriver.mdd');
            try
                connect(app.spectrometerObj)
                app.isConnected = true;
            catch
                app.NumOfSpectrometersLabel.Text = 'No Devices Found';
                return
            end

            spectrometerName = invoke(app.spectrometerObj, 'getName', app.spectrometerIndex);

            % Get number of spectrometers connected
            app.numOfSpectrometers = invoke(app.spectrometerObj, 'getNumberOfSpectrometersFound');
            app.NumOfSpectrometersLabel.Text = [num2str(app.numOfSpectrometers), ' ', spectrometerName, ' Connected'];

            for i = 1:app.numOfSpectrometers
                index = i - 1;

                % Get spectrometer serial number
                spectrometerSerialNumber = invoke(app.spectrometerObj, 'getSerialNumber', index);

                switch i
                    case 1
                        app.Spectrometer1CheckBox.Text = spectrometerSerialNumber;
                        app.legendLabel = [app.legendLabel; spectrometerSerialNumber];
                        app.Spectrometer1CheckBox.Value = 1;
                    case 2
                        app.Spectrometer2CheckBox.Text = spectrometerSerialNumber;
                        app.legendLabel = [app.legendLabel; spectrometerSerialNumber];
                        app.Spectrometer2CheckBox.Value = 1;
                        app.activeIndex = sort(unique([app.activeIndex, i - 1]));
                    case 3
                        app.Spectrometer3CheckBox.Text = spectrometerSerialNumber;
                        app.legendLabel = [app.legendLabel; spectrometerSerialNumber];
                        app.Spectrometer3CheckBox.Value = 1;
                        app.activeIndex = sort(unique([app.activeIndex, i - 1]));
                    case 4
                        app.Spectrometer4CheckBox.Text = spectrometerSerialNumber;
                        app.legendLabel = [app.legendLabel; spectrometerSerialNumber];
                        app.Spectrometer4CheckBox.Value = 1;
                        app.activeIndex = sort(unique([app.activeIndex, i - 1]));
                end

                app.SelectSpectrometerDropDown.Items(i) = cellstr(spectrometerSerialNumber);

                % Enable ElectricalDark and Nonlinearity calibration
                invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', index, app.channelIndex, 1);
                invoke(app.spectrometerObj, 'setCorrectForElectricalDark', index, app.channelIndex, 1);
            end

            if app.numOfSpectrometers
                app.StartLiveViewButton.Enable = 'on';
            end
            app.ConnectSpectrometersButton.Enable = 'off';
            app.IntegrationTimesEditField.Editable = 'on';

        end

        % Value changed function: AutomaticButton
        function AutomaticButtonValueChanged(app, event)
            i = app.SelectSpectrometerDropDown.Value + 1;
            app.isAuto(i) = app.AutomaticButton.Value;
            
            % Determines optimal integration time by spectral intensity every 3 seconds
            app.scanToAvg = 4;
            app.scanIndex = 1;
            app.scanMaxValues = zeros(app.scanToAvg, 1);

            if app.isAuto(i)
                app.integrationTimeOld(i) = app.IntegrationTimesEditField.Value;
            else
                app.IntegrationTimesEditField.Value = app.integrationTimeOld(i);
                app.IntegrationTimesEditFieldValueChanged();
            end

        end

        % Value changed function: SelectSpectrometerDropDown
        function SelectSpectrometerDropDownValueChanged(app, event)
            app.spectrometerIndex = app.SelectSpectrometerDropDown.Value;

            for i = app.spectrometerIndex
                app.IntegrationTimesEditField.Value = app.integrationTime(i + 1) / 1000000;
                invoke(app.spectrometerObj, 'setIntegrationTime', i, app.channelIndex, app.integrationTime(i + 1));
                app.AutomaticButton.Value = app.isAuto(i + 1);
            end
            
        end

        % Value changed function: IntegrationTimesEditField
        function IntegrationTimesEditFieldValueChanged(app, event)
            if app.init
                for i = 1:app.numOfSpectrometers
                    index = i - 1;
                    app.integrationTime(i) = app.IntegrationTimesEditField.Value * 1000000;
                    invoke(app.spectrometerObj, 'setIntegrationTime', index, app.channelIndex, app.integrationTime(i));
                    app.isAuto(i) = 0;
                end
            else
                app.integrationTime(app.spectrometerIndex + 1) = app.IntegrationTimesEditField.Value * 1000000;
                invoke(app.spectrometerObj, 'setIntegrationTime', app.spectrometerIndex, app.channelIndex, app.integrationTime(app.spectrometerIndex + 1));
            end

        end

        % Button pushed function: StartLiveViewButton
        function StartLiveViewButtonPushed(app, event)
            start(app.timeObj);
            start(app.timeRun);
            app.StartLiveViewButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.SetBGButton.Enable = 'on';
            app.NoBGButton.Enable = 'on';
            app.SaveSpectrumButton.Enable = 'on';
            app.SaveRawSpectrumandBGButton.Enable = 'on';
            app.AutomaticButton.Enable = 'on';
            app.SelectSpectrometerDropDown.Enable = 'on';
            app.isRunning = true;
            app.IntegrationTimesEditFieldValueChanged();

            for i = 1:app.numOfSpectrometers
                switch i
                    case 1
                        app.Spectrometer1CheckBox.Enable = 'on';
                    case 2
                        app.Spectrometer2CheckBox.Enable = 'on';
                    case 3
                        app.Spectrometer3CheckBox.Enable = 'on';
                    case 4
                        app.Spectrometer4CheckBox.Enable = 'on';
                end
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.timeObj)
            stop(app.timeRun)
            app.StartLiveViewButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.isRunning = false;

        end

        % Button pushed function: SetBGButton
        function SetBGButtonPushed(app, event)
            for i = 0:app.numOfSpectrometers - 1
                app.bgSpectrum{i + 1} = app.rawSpectrum{i + 1};
            end

        end

        % Button pushed function: NoBGButton
        function NoBGButtonPushed(app, event)
            app.bgSpectrum = cell(1, app.numOfSpectrometers);
            for i = 0:app.numOfSpectrometers - 1
                app.bgSpectrum{i + 1} = zeros(size(app.rawSpectrum{i + 1}));
            end

        end

        % Button pushed function: SaveSpectrumButton
        function SaveSpectrumButtonPushed(app, event)
            fmts = {'*.mat'; '*.txt'};
            defaultPath = '../Save/';
            if ~isfolder(defaultPath)
                mkdir(defaultPath)
            end
            prefix = datestr(now, 'yymmdd_HHMM');
            [file, path, indx] = uiputfile(fmts, 'Save File', [defaultPath prefix '_']);

            wavelengthData = single(cell2mat(app.wavelength));
            rawSpectralData = single(cell2mat(app.rawSpectrum));
            bg = single(cell2mat(app.bgSpectrum));
            spectralData = rawSpectralData - bg;

            if indx == 1
                save(fullfile(path, file), 'wavelengthData', 'spectralData');
            elseif indx == 2
                collectedData = {};
                
                for i = 1:numel(app.activeIndex)
                    cellData = [wavelengthData(:, i), rawSpectralData(:, i) - bg(:, i)];
                    collectedData{i} = cellData;
                end
                
                matrixData = cell2mat(collectedData);
                writematrix(matrixData, fullfile(path, file), 'Delimiter', '\t');
            end

            figure(app.UIFigure)

        end

        % Button pushed function: SaveRawSpectrumandBGButton
        function SaveRawSpectrumandBGButtonPushed(app, event)
            fmts = {'*.mat'; '*.txt'};
            defaultPath = '../Save/';
            prefix = datestr(now, 'yymmdd_HHMM_raw');
            [file, path, indx] = uiputfile(fmts, 'Save File', [defaultPath prefix '_']);

            wavelengthData = single(cell2mat(app.wavelength));
            rawSpectralData = single(cell2mat(app.rawSpectrum));
            bg = single(cell2mat(app.bgSpectrum));

            if indx == 1
                save(fullfile(path, file), 'wavelengthData', 'rawSpectralData', 'bg');
            elseif indx == 2
                collectedData = {};
    
                for i = 1:numel(app.activeIndex)
                    cellData = [wavelengthData(:, i), rawSpectralData(:, i), bg(:, i)];
                    collectedData{i} = cellData;
                end
    
                matrixData = cell2mat(collectedData);
                writematrix(matrixData, fullfile(path, file), 'Delimiter', '\t');
            end

            figure(app.UIFigure)

        end

        % Callback function: ExitButton, UIFigure
        function UIFigureCloseRequest(app, event)
            if app.isConnected
                listOfTimers = timerfindall;
                stop(listOfTimers(:))
                delete(listOfTimers(:))
                disconnect(app.spectrometerObj)
                delete(app.spectrometerObj)
            end
            delete(gcp('nocreate'))
            delete(app)

        end

        % Value changed function: Spectrometer1CheckBox
        function Spectrometer1CheckBoxValueChanged(app, event)
            showSpectrum = app.Spectrometer1CheckBox.Value;
            index = 0;

            if showSpectrum
                app.activeIndex = sort(unique([app.activeIndex, index]));
                invoke(app.spectrometerObj, 'setIntegrationTime', index, app.channelIndex, app.integrationTime(index + 1));
                invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', index, app.channelIndex, 1);
                invoke(app.spectrometerObj, 'setCorrectForElectricalDark', index, app.channelIndex, 1);
            else
                app.activeIndex = setdiff(app.activeIndex, index);
            end
            

        end

        % Value changed function: Spectrometer2CheckBox
        function Spectrometer2CheckBoxValueChanged(app, event)
            showSpectrum = app.Spectrometer2CheckBox.Value;
            index = 1;

            if showSpectrum
                app.activeIndex = sort(unique([app.activeIndex, index]));
                invoke(app.spectrometerObj, 'setIntegrationTime', index, app.channelIndex, app.integrationTime(index + 1));
                invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', index, app.channelIndex, 1);
                invoke(app.spectrometerObj, 'setCorrectForElectricalDark', index, app.channelIndex, 1);
            else
                app.activeIndex = setdiff(app.activeIndex, index);
            end

        end

        % Value changed function: Spectrometer3CheckBox
        function Spectrometer3CheckBoxValueChanged(app, event)
            showSpectrum = app.Spectrometer3CheckBox.Value;
            index = 2;

            if showSpectrum
                app.activeIndex = sort(unique([app.activeIndex, index]));
                invoke(app.spectrometerObj, 'setIntegrationTime', index, app.channelIndex, app.integrationTime(index + 1));
                invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', index, app.channelIndex, 1);
                invoke(app.spectrometerObj, 'setCorrectForElectricalDark', index, app.channelIndex, 1);
            else
                app.activeIndex = setdiff(app.activeIndex, index);
            end

        end

        % Value changed function: Spectrometer4CheckBox
        function Spectrometer4CheckBoxValueChanged(app, event)
            showSpectrum = app.Spectrometer4CheckBox.Value;
            index = 3;

            if showSpectrum
                app.activeIndex = sort(unique([app.activeIndex, index]));
                invoke(app.spectrometerObj, 'setIntegrationTime', index, app.channelIndex, app.integrationTime(index + 1));
                invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', index, app.channelIndex, 1);
                invoke(app.spectrometerObj, 'setCorrectForElectricalDark', index, app.channelIndex, 1);
            else
                app.activeIndex = setdiff(app.activeIndex, index);
            end

        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            prompt = {'Enter matrix size:','Enter colormap name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'20','hsv'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {608, 608};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.ExitButton = uibutton(app.LeftPanelGrid, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.NumOfSpectrometersLabel = uilabel(app.LeftPanelGrid);
            app.NumOfSpectrometersLabel.Text = '';
            app.ConnectSpectrometersButton = uibutton(app.LeftPanelGrid, 'push');
            app.ConnectSpectrometersButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSpectrometersButtonPushed, true);
            app.ConnectSpectrometersButton.Text = 'Connect Spectrometer';
            app.ControlGroup = uitabgroup(app.LeftPanelGrid);
            app.MainControlsTab = uitab(app.ControlGroup);
            app.MainControlsTab.Title = 'Main Controls';
            app.MainGridLayout = uigridlayout(app.MainControlsTab);
            app.RunTimeLabel = uilabel(app.MainGridLayout);
            app.RunTimeLabel.Tooltip = {'(HH:MM:SS)'};
            app.RunTimeLabel.Text = 'Run Time';
            app.RunTime = uilabel(app.MainGridLayout);
            app.RunTime.Text = '00:00:00';
            app.AutomaticButton = uibutton(app.MainGridLayout, 'state');
            app.AutomaticButton.ValueChangedFcn = createCallbackFcn(app, @AutomaticButtonValueChanged, true);
            app.AutomaticButton.Enable = 'off';
            app.AutomaticButton.Tooltip = {'Sets integration time to recommended peak value.'; 'Click Automatic to Start/Stop autoset process.'};
            app.AutomaticButton.Text = 'Automatic';
            app.SelectSpectrometerDropDown = uidropdown(app.MainGridLayout);
            app.SelectSpectrometerDropDown.Items = {};
            app.SelectSpectrometerDropDown.ItemsData = [0 1 2 3];
            app.SelectSpectrometerDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSpectrometerDropDownValueChanged, true);
            app.SelectSpectrometerDropDown.Enable = 'off';
            app.SelectSpectrometerDropDown.Tooltip = {'Select different device to change the integration time.'};
            app.SelectSpectrometerDropDown.Placeholder = 'N/A';
            app.SelectSpectrometerDropDown.Value = {};
            app.IntegrationTimesEditField = uieditfield(app.MainGridLayout, 'numeric');
            app.IntegrationTimesEditField.Limits = [0.008 8];
            app.IntegrationTimesEditField.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimesEditFieldValueChanged, true);
            app.IntegrationTimesEditField.Editable = 'off';
            app.IntegrationTimesEditField.Tooltip = {'Max: 8s'; 'Min: 0.008s'; 'Default: 0.1s'};
            app.IntegrationTimesEditField.Value = 0.1;
            app.StartLiveViewButton = uibutton(app.MainGridLayout, 'push');
            app.StartLiveViewButton.ButtonPushedFcn = createCallbackFcn(app, @StartLiveViewButtonPushed, true);
            app.StartLiveViewButton.Enable = 'off';
            app.StartLiveViewButton.Text = 'Start Live View';
            app.StopButton = uibutton(app.MainGridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.SetBGButton = uibutton(app.MainGridLayout, 'push');
            app.SetBGButton.ButtonPushedFcn = createCallbackFcn(app, @SetBGButtonPushed, true);
            app.SetBGButton.Enable = 'off';
            app.SetBGButton.Tooltip = {'Subtract background and set current reading to zero.'};
            app.SetBGButton.Text = 'Set BG';
            app.NoBGButton = uibutton(app.MainGridLayout, 'push');
            app.NoBGButton.ButtonPushedFcn = createCallbackFcn(app, @NoBGButtonPushed, true);
            app.NoBGButton.Enable = 'off';
            app.NoBGButton.Tooltip = {'Remove background value'};
            app.NoBGButton.Text = 'No BG';
            app.SaveSpectrumButton = uibutton(app.MainGridLayout, 'push');
            app.SaveSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSpectrumButtonPushed, true);
            app.SaveSpectrumButton.Enable = 'off';
            app.SaveSpectrumButton.Text = 'Save Spectrum';
            app.SaveRawSpectrumandBGButton = uibutton(app.MainGridLayout, 'push');
            app.SaveRawSpectrumandBGButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRawSpectrumandBGButtonPushed, true);
            app.SaveRawSpectrumandBGButton.Enable = 'off';
            app.SaveRawSpectrumandBGButton.Text = 'Save Raw Spectrum, and BG';
            app.IntegrationTimesEditFieldLabel = uilabel(app.MainGridLayout);
            app.IntegrationTimesEditFieldLabel.Tooltip = {'(s)'};
            app.IntegrationTimesEditFieldLabel.Text = 'Integration Time';
            app.AbsoluteIrradianceButton = uibutton(app.MainGridLayout, 'push');
            app.AbsoluteIrradianceButton.Enable = 'off';
            app.AbsoluteIrradianceButton.Tooltip = {'Once an absolute irradiance spectrum is measured, it can be used to calculate power measurements of radiant sources.'};
            app.AbsoluteIrradianceButton.Text = 'Absolute Irradiance';
            app.GeographicTab = uitab(app.ControlGroup);
            app.GeographicTab.Title = 'Geographic';
            app.GeoGridLayout = uigridlayout(app.GeographicTab);
            app.LatitudeEditFieldLabel = uilabel(app.GeoGridLayout);
            app.LatitudeEditFieldLabel.Tooltip = {'(degree)'};
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.GeoGridLayout, 'numeric');
            app.LatitudeEditField.Limits = [-90 90];
            app.LatitudeEditField.ValueDisplayFormat = '%11.8g';
            app.LatitudeEditField.Tooltip = {''};
            app.LatitudeEditField.Value = 23.5084;
            app.LongitudeEditFieldLabel = uilabel(app.GeoGridLayout);
            app.LongitudeEditFieldLabel.Tooltip = {'(degree)'};
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.GeoGridLayout, 'numeric');
            app.LongitudeEditField.Limits = [-180 180];
            app.LongitudeEditField.ValueDisplayFormat = '%11.8g';
            app.LongitudeEditField.Tooltip = {''};
            app.LongitudeEditField.Value = 120.8108;
            app.ElevationEditFieldLabel = uilabel(app.GeoGridLayout);
            app.ElevationEditFieldLabel.Tooltip = {'(m)'};
            app.ElevationEditFieldLabel.Text = 'Elevation';
            app.ElevationEditField = uieditfield(app.GeoGridLayout, 'numeric');
            app.ElevationEditField.ValueDisplayFormat = '%11.8g';
            app.ElevationEditField.Tooltip = {''};
            app.ElevationEditField.Value = 2413.4;
            app.PressureEditFieldLabel = uilabel(app.GeoGridLayout);
            app.PressureEditFieldLabel.Tooltip = {'(millibars)'};
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.PressureEditField = uieditfield(app.GeoGridLayout, 'numeric');
            app.PressureEditField.ValueDisplayFormat = '%11.8g';
            app.PressureEditField.Tooltip = {''};
            app.PressureEditField.Value = 1014;
            app.TemperatureEditFieldLabel = uilabel(app.GeoGridLayout);
            app.TemperatureEditFieldLabel.Tooltip = {'(celsius)'};
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.GeoGridLayout, 'numeric');
            app.TemperatureEditField.ValueDisplayFormat = '%11.8g';
            app.TemperatureEditField.Tooltip = {''};
            app.TemperatureEditField.Value = 25;
            app.SlopeEditFieldLabel = uilabel(app.GeoGridLayout);
            app.SlopeEditFieldLabel.Tooltip = {'(degree)'};
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.GeoGridLayout, 'numeric');
            app.SlopeEditField.ValueDisplayFormat = '%11.8g';
            app.SlopeEditField.Tooltip = {''};
            app.AZMRotationEditFieldLabel = uilabel(app.GeoGridLayout);
            app.AZMRotationEditFieldLabel.Tooltip = {'(degree)'};
            app.AZMRotationEditFieldLabel.Text = 'AZM Rotation';
            app.AZMRotationEditField = uieditfield(app.GeoGridLayout, 'numeric');
            app.AZMRotationEditField.ValueDisplayFormat = '%11.8g';
            app.AZMRotationEditField.Tooltip = {''};
            app.TimeZoneEditFieldLabel = uilabel(app.GeoGridLayout);
            app.TimeZoneEditFieldLabel.Tooltip = {'(UTC)'};
            app.TimeZoneEditFieldLabel.Text = 'Time Zone';
            app.TimeZoneEditField = uieditfield(app.GeoGridLayout, 'text');
            app.TimeZoneEditField.Tooltip = {''};
            app.TimeZoneEditField.Value = '+8';
            app.SZALabel = uilabel(app.GeoGridLayout);
            app.SZALabel.Tooltip = {'(degree)'};
            app.SZALabel.Text = 'Solar Zenith Angle';
            app.SolarZenithAngle = uilabel(app.GeoGridLayout);
            app.SolarZenithAngle.Text = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.PlotGrid = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.PlotGrid);
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Intensity (counts)')
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.SpectrometersGrid = uigridlayout(app.PlotGrid);
            app.Spectrometer1CheckBox = uicheckbox(app.SpectrometersGrid);
            app.Spectrometer1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Spectrometer1CheckBoxValueChanged, true);
            app.Spectrometer1CheckBox.Enable = 'off';
            app.Spectrometer1CheckBox.Text = 'Spectrometer1';
            app.Spectrometer2CheckBox = uicheckbox(app.SpectrometersGrid);
            app.Spectrometer2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Spectrometer2CheckBoxValueChanged, true);
            app.Spectrometer2CheckBox.Enable = 'off';
            app.Spectrometer2CheckBox.Text = 'Spectrometer2';
            app.Spectrometer3CheckBox = uicheckbox(app.SpectrometersGrid);
            app.Spectrometer3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Spectrometer3CheckBoxValueChanged, true);
            app.Spectrometer3CheckBox.Enable = 'off';
            app.Spectrometer3CheckBox.Text = 'Spectrometer3';
            app.Spectrometer4CheckBox = uicheckbox(app.SpectrometersGrid);
            app.Spectrometer4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Spectrometer4CheckBoxValueChanged, true);
            app.Spectrometer4CheckBox.Enable = 'off';
            app.Spectrometer4CheckBox.Text = 'Spectrometer4';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OmniSpectrometer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end